classdef holeDetectionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        NumberofHolesDetectedEditField  matlab.ui.control.NumericEditField
        NumberofHolesDetectedEditFieldLabel  matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        UploadImageButton               matlab.ui.control.Button
        NIOSHDroneFallHazardDetectionLabel  matlab.ui.control.Label
        DetectHolesButton               matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        detector;  % Store the trained detector
    end
    
    properties (Access = private)
        IsDetectorLoaded = false; 
        IsImageLoaded = false;

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load the trained detector
            app.detector = load('Detector.mat');
            app.IsDetectorLoaded = true;
            axis(app.UIAxes,'off')
            axis(app.UIAxes2,'off')
        end

        % Button pushed function: DetectHolesButton
        function DetectHolesButtonPushed(app, event)
           % if ~isfield(app, 'detector')
           %      % If the detector is not loaded, display an error message
           %      msgbox('Please load the trained detector first.', 'Error', 'error');
           %      return;
           % end
           if app.IsImageLoaded

           else
               msgbox('Please upload an image first.', 'Error', 'error');
               return;
           end
           % Get the displayed image
           I = app.UIAxes.Children.CData;
           % Detect holes and display the results
           [bboxes, scores] = detect(app.detector.detector, I, 'SelectStrongest',false);
           [selectedBbox,selectedScore] = selectStrongestBbox(bboxes,scores,'OverlapThreshold',0.1);
           annotations = strings(length(selectedScore),1);
           for i=1:length(selectedScore)
               annotations{i} = [num2str(selectedScore(i),'%0.2f') '%'];
           end
           % detectedImage = insertObjectAnnotation(I, 'rectangle', bboxes, annotations);
           app.NumberofHolesDetectedEditField.Value = length(selectedScore);
           detectedImage = insertObjectAnnotation(I,'rectangle',selectedBbox,annotations);
           imshow(detectedImage, 'Parent', app.UIAxes2);
 
        end

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            % Load the image
            [filename, filepath] = uigetfile({'*.png';'*.jpg'}, 'Select an image');
            if isequal(filename, 0)
                return; % User canceled
            end
            % Display the selected image in the UIAxes
            I = imread(fullfile(filepath, filename));
            imshow(I, 'Parent', app.UIAxes);
            app.IsImageLoaded = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Uploaded Image')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Detected Image')
            zlabel(app.UIAxes2, 'Z')
            app.DetectHolesButton = uibutton(app.UIFigure, 'push');
            app.DetectHolesButton.ButtonPushedFcn = createCallbackFcn(app, @DetectHolesButtonPushed, true);
            app.DetectHolesButton.Text = 'Detect Holes';
            app.NIOSHDroneFallHazardDetectionLabel = uilabel(app.UIFigure);
            app.NIOSHDroneFallHazardDetectionLabel.Text = 'NIOSH - Drone Fall Hazard Detection';
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'ise-symbol-logo-01.jpg');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'NioshLogo.png';
            app.NumberofHolesDetectedEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofHolesDetectedEditFieldLabel.Text = 'Number of Holes Detected';
            app.NumberofHolesDetectedEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = holeDetectionApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end